classdef Prime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PrimesSpiral                  matlab.ui.Figure
        NumberofprimesEditField       matlab.ui.control.EditField
        NumberofprimesEditFieldLabel  matlab.ui.control.Label
        VisualizeButton               matlab.ui.control.Button
        PrimesfoundTextArea           matlab.ui.control.TextArea
        PrimesfoundTextAreaLabel      matlab.ui.control.Label
        FindPrimesuptoEditField       matlab.ui.control.NumericEditField
        FindPrimesuptoEditFieldLabel  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        UpperLimit % horni limit do ktereho se budou hledat primes
        PrimeNumbers % vektor obsahujici pouze prvocisla
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FindPrimesuptoEditField.Value = 1000000;
            app.UpperLimit = app.FindPrimesuptoEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.UpperLimit <= 1
                uialert(app.PrimesSpiral,"That is too low for my tastes. Try again.", 'Value Too Low');
                % should not be needed
            else
                if app.UpperLimit >= 5000000
                    uialert(app.PrimesSpiral,"This might take a while.", 'Crack Open A Cold One','Icon','info');
                end
                app.PrimeNumbers = findprimes(app.UpperLimit);
                app.PrimesfoundTextArea.Value = num2str(app.PrimeNumbers);
                app.NumberofprimesEditField.Value = num2str(size(app.PrimeNumbers,2));
            end
        end

        % Value changed function: FindPrimesuptoEditField
        function FindPrimesuptoEditFieldValueChanged(app, event)
            app.UpperLimit = app.FindPrimesuptoEditField.Value;
        end

        % Value changed function: PrimesfoundTextArea
        function PrimesfoundTextAreaValueChanged(app, event)
%             value = app.PrimesfoundTextArea.Value;
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            if isempty(app.PrimeNumbers)
                uialert(app.PrimesSpiral,"I need to find the primes first. I have nothing to show you.", 'Find Primes First');
            else
                ps = figure;
                ps.WindowState = 'maximized';
                polarscatter(app.PrimeNumbers,app.PrimeNumbers,'filled','MarkerFaceColor', 'black','SizeData', 2);
                axis off;
            end
        end

        % Callback function
        function NumberofprimesEditFieldValueChanged(app, event)
%             value = app.NumberofprimesEditField.Value;
            
        end

        % Value changed function: NumberofprimesEditField
        function NumberofprimesEditFieldValueChanged2(app, event)
%             value = app.NumberofprimesEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PrimesSpiral = uifigure('Visible', 'off');
            app.PrimesSpiral.Name = 'Primes Spiral';
            app.StartButton = uibutton(app.PrimesSpiral, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FindPrimesuptoEditFieldLabel = uilabel(app.PrimesSpiral);
            app.FindPrimesuptoEditFieldLabel.Text = 'Find Primes up to:';
            app.FindPrimesuptoEditField = uieditfield(app.PrimesSpiral, 'numeric');
            app.FindPrimesuptoEditField.Limits = [2 Inf];
            app.FindPrimesuptoEditField.RoundFractionalValues = 'on';
            app.FindPrimesuptoEditField.ValueDisplayFormat = '%.0f';
            app.FindPrimesuptoEditField.ValueChangedFcn = createCallbackFcn(app, @FindPrimesuptoEditFieldValueChanged, true);
            app.FindPrimesuptoEditField.Value = 1000000;
            app.PrimesfoundTextAreaLabel = uilabel(app.PrimesSpiral);
            app.PrimesfoundTextAreaLabel.Text = 'Primes found';
            app.PrimesfoundTextArea = uitextarea(app.PrimesSpiral);
            app.PrimesfoundTextArea.ValueChangedFcn = createCallbackFcn(app, @PrimesfoundTextAreaValueChanged, true);
            app.PrimesfoundTextArea.Editable = 'off';
            app.VisualizeButton = uibutton(app.PrimesSpiral, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = 'Visualize';
            app.NumberofprimesEditFieldLabel = uilabel(app.PrimesSpiral);
            app.NumberofprimesEditFieldLabel.Text = 'Number of primes';
            app.NumberofprimesEditField = uieditfield(app.PrimesSpiral, 'text');
            app.NumberofprimesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofprimesEditFieldValueChanged2, true);
            app.NumberofprimesEditField.Editable = 'off';
            % Show the figure after all components are created
            app.PrimesSpiral.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prime

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PrimesSpiral)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PrimesSpiral)
        end
    end
end